classdef LeafAnalysis_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MASSV10UIFigure               matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        elliptical_fourier_checkbox   matlab.ui.control.CheckBox
        LabelNumericEditField2        matlab.ui.control.Label
        num_harmonics_input           matlab.ui.control.NumericEditField
        landmarks_checkbox            matlab.ui.control.CheckBox
        Label                         matlab.ui.control.Label
        num_landmarks_input           matlab.ui.control.NumericEditField
        run_button                    matlab.ui.control.Button
        reset_button                  matlab.ui.control.Button
        quit_button                   matlab.ui.control.Button
        standaloenGroup               matlab.ui.container.ButtonGroup
        procrustes_checkbox           matlab.ui.control.CheckBox
        pca_checkbox                  matlab.ui.control.CheckBox
        outline_superimpose_checkbox  matlab.ui.control.CheckBox
        runModeGroup_2                matlab.ui.container.ButtonGroup
        cm_in_dropdown                matlab.ui.control.DropDown
        LabelNumericEditField         matlab.ui.control.Label
        measurements_input            matlab.ui.control.NumericEditField
        wider_checkbox                matlab.ui.control.CheckBox
        NumberofExteriorPointsLabel   matlab.ui.control.Label
        ext_points_input              matlab.ui.control.NumericEditField
        LineSegmentsConnectingPointsLabel  matlab.ui.control.Label
        landmark_links_input          matlab.ui.control.EditField
        tip_angle_checkbox            matlab.ui.control.CheckBox
        rotate_45_checkbox            matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: run_button
        function run_buttonButtonPushed(app, event)
            % Get current value of each checkbox (1 if pressed, 0 if not)
            efdChecked = app.elliptical_fourier_checkbox.Value;
            landmarksChecked = app.landmarks_checkbox.Value;
            procrustesChecked = app.procrustes_checkbox.Value;
            pcaChecked = app.pca_checkbox.Value;
            superimposeOutlinesChecked = app.outline_superimpose_checkbox.Value;
            widerChecked = app.wider_checkbox.Value;
            tipAngleChecked = app.tip_angle_checkbox.Value;
            rotate45Checked = app.rotate_45_checkbox.Value;
            choices = [efdChecked, landmarksChecked, procrustesChecked, pcaChecked, superimposeOutlinesChecked, widerChecked, tipAngleChecked, rotate45Checked];
            
            % Get the ruler measurement (read as double)
            measurementVal = app.measurements_input.Value;
            % If the user prefers to input the measurements manually for each
            % leaf, then leave as zero
            % Read units (type of variable is char ie. string)
            measurementUnits = app.cm_in_dropdown.Value;
 
            % get number of harmonics
            numHarmonics = app.num_harmonics_input.Value;
            % get number of landmarks
            numLandmarks = app.num_landmarks_input.Value;
            % get number of exterior landmarks
            numExtPts = app.ext_points_input.Value;
            % Get links of landmarks
            landmarkLinks = app.landmark_links_input.Value;
            
            % Call the functions and pass the value
           Leaf_Analysis_V6_GUI(measurementVal, measurementUnits, choices, numHarmonics, numLandmarks, numExtPts, landmarkLinks); 
        end

        % Button pushed function: quit_button
        function quit_buttonButtonPushed(app, event)
            closereq;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MASSV10UIFigure = uifigure;
            app.MASSV10UIFigure.Name = 'MASS V 1.0';
            app.Panel = uipanel(app.MASSV10UIFigure);
            app.Panel.Title = 'Quantitative Leaf Analysis';
            app.elliptical_fourier_checkbox = uicheckbox(app.Panel);
            app.elliptical_fourier_checkbox.Text = 'Elliptical Fourier';
            app.LabelNumericEditField2 = uilabel(app.Panel);
            app.LabelNumericEditField2.Text = 'Number of Harmonics';
            app.num_harmonics_input = uieditfield(app.Panel, 'numeric');
            app.num_harmonics_input.Value = 1;
            app.landmarks_checkbox = uicheckbox(app.Panel);
            app.landmarks_checkbox.Text = 'Landmarks';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Number of Landmarks';
            app.num_landmarks_input = uieditfield(app.Panel, 'numeric');
            app.num_landmarks_input.Value = 1;
            app.run_button = uibutton(app.Panel, 'push');
            app.run_button.ButtonPushedFcn = createCallbackFcn(app, @run_buttonButtonPushed, true);
            app.run_button.Text = 'Run Analysis';
            app.reset_button = uibutton(app.Panel, 'push');
            app.reset_button.Text = 'Reset';
            app.quit_button = uibutton(app.Panel, 'push');
            app.quit_button.ButtonPushedFcn = createCallbackFcn(app, @quit_buttonButtonPushed, true);
            app.quit_button.Text = 'Quit';
            app.standaloenGroup = uibuttongroup(app.Panel);
            app.standaloenGroup.Title = 'Standalone Tools';
            app.procrustes_checkbox = uicheckbox(app.standaloenGroup);
            app.procrustes_checkbox.Text = 'Procrustes Analysis';
            app.pca_checkbox = uicheckbox(app.standaloenGroup);
            app.pca_checkbox.Text = 'Principal Component Analysis';
            app.outline_superimpose_checkbox = uicheckbox(app.standaloenGroup);
            app.outline_superimpose_checkbox.Text = 'Outline Superimposition';
            app.runModeGroup_2 = uibuttongroup(app.Panel);
            app.runModeGroup_2.Title = 'Basic Analysis';
            app.cm_in_dropdown = uidropdown(app.runModeGroup_2);
            app.cm_in_dropdown.Items = {'cm', 'in'};
            app.cm_in_dropdown.Value = 'cm';
            app.LabelNumericEditField = uilabel(app.runModeGroup_2);
            app.LabelNumericEditField.Text = 'Measurement';
            app.measurements_input = uieditfield(app.runModeGroup_2, 'numeric');
            app.measurements_input.Value = 1;
            app.wider_checkbox = uicheckbox(app.runModeGroup_2);
            app.wider_checkbox.Text = 'Width > Length';
            app.NumberofExteriorPointsLabel = uilabel(app.Panel);
            app.NumberofExteriorPointsLabel.Text = 'Number of Exterior Points';
            app.ext_points_input = uieditfield(app.Panel, 'numeric');
            app.ext_points_input.Value = 1;
            app.LineSegmentsConnectingPointsLabel = uilabel(app.Panel);
            app.LineSegmentsConnectingPointsLabel.Text = 'Line Segments Connecting Points:';
            app.landmark_links_input = uieditfield(app.Panel, 'text');
            app.tip_angle_checkbox = uicheckbox(app.Panel);
            app.tip_angle_checkbox.Text = 'Leaf Tip Angle';
            app.rotate_45_checkbox = uicheckbox(app.Panel);
            app.rotate_45_checkbox.Text = 'Rotate 45 degrees';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LeafAnalysis_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MASSV10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MASSV10UIFigure)
        end
    end
end